classdef financalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        DatePicker          matlab.ui.control.DatePicker
        UITable             matlab.ui.control.Table
        Label_2             matlab.ui.control.Label
        DropDownClass       matlab.ui.control.DropDown
        ButtonAddRecord     matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        EditFieldValue      matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        EditFieldRemarks    matlab.ui.control.EditField
        ButtonUpdateRecord  matlab.ui.control.Button
    end

    
%     properties (Access = private)
%         ControllerObj
%         ModelObj
%     end
    
    properties (Access = public)
        ControllerObj
        ModelObj
        Record
    end
    
    methods (Access = private)
        
        function attachToController(app,controllerObj)
            app.ButtonAddRecord.addlistener('ButtonPushed',@controllerObj.callback_addRecord)
            app.ButtonUpdateRecord.addlistener('ButtonPushed',@controllerObj.callback_updateRecord)
        end
        function updateData(app,src,data)
            
            app.UITable.Data = app.ModelObj.outDataTable;
            disp('updateData!')
            app.UITable.Data
        end
    end
    
    methods (Access = public)
        
        function test_updateData(app,src,data)
            app.ModelObj.outDataTable
           disp('updateData!') 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.ModelObj = model();
             app.ControllerObj = controller(app,app.ModelObj);
             app.attachToController(app.ControllerObj);
             app.ModelObj.addlistener('updateData',@app.updateData); 
             app.UITable.ColumnName = {'Data';'Value';'Type';'Remarks'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '时间';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '类型';
            app.DropDownClass = uidropdown(app.UIFigure);
            app.DropDownClass.Items = {'支出', '收入'};
            app.DropDownClass.Value = '支出';
            app.ButtonAddRecord = uibutton(app.UIFigure, 'push');
            app.ButtonAddRecord.Text = '增加数据';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '数值';
            app.EditFieldValue = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '备注';
            app.EditFieldRemarks = uieditfield(app.UIFigure, 'text');
            app.ButtonUpdateRecord = uibutton(app.UIFigure, 'push');
            app.ButtonUpdateRecord.Text = '刷新数据';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = financalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end